classdef signal_processer_tool_emin < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        Label_8                  matlab.ui.control.Label
        Label_7                  matlab.ui.control.Label
        ExportDataButton         matlab.ui.control.Button
        RECORDButton             matlab.ui.control.Button
        DropDown                 matlab.ui.control.DropDown
        Switch                   matlab.ui.control.ToggleSwitch
        Lamp                     matlab.ui.control.Lamp
        Label_6                  matlab.ui.control.Label
        Slider_2                 matlab.ui.control.Slider
        Slider                   matlab.ui.control.RangeSlider
        BandpassFcsforLungLabel  matlab.ui.control.Label
        LowFcforHeartLabel       matlab.ui.control.Label
        Knob2                    matlab.ui.control.DiscreteKnob
        Label_5                  matlab.ui.control.Label
        BPMLabel                 matlab.ui.control.Label
        Label_4                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    properties (Access = public)
        arduinoObj
        breathHigh = 0.2;
        breathLow = 0.4;
        count = 0;
        data = [];
        filterOrder = 3;
        filterType = 'Heart';
        filteredData = [];
        fs = 500;
        heartLow = 0.2;
        pinNumber = 'A0';
        port = 'COM3';
        storedData
        time = [];
        yMax = 2;
        yMin = -2;
    end
    
    
    methods (Access = public)
        
        function clearDatas(comp)

        if isprop(comp, 'Label') && isprop(comp.Label, 'Text')
            comp.Label.Text = '---';
        end
    
        comp.data = [];
        comp.time = [];
        comp.count = 0;
    end

        
        % plot function
        function result = plotOrginalData(comp)
            %lineColor: 'b','k', 'r'
            hold(comp.UIAxes, 'on');
            plottedGraph = plot(comp.UIAxes, 0, 0, 'b');
            axis(comp.UIAxes, [0 10 comp.yMin comp.yMax]);
            result = plottedGraph;
        end

        function result = plotFiltered(comp)
            %lineColor: 'b','k', 'r'
            hold(comp.UIAxes_2, 'on');
            plottedGraph = plot(comp.UIAxes_2, 0, 0, 'k');
            axis(comp.UIAxes_2, [0 10 comp.yMin comp.yMax]);
            result = plottedGraph;
        end

        % filter function

        function result = applyFilter(comp)
            disp(['breathLow: ', comp.breathLow]);
 
            disp(['breathHigh: ', comp.breathHigh]);
            disp(['heartLow: ', comp.heartLow]);
            if strcmp(comp.filterType,'Heart')
                [b, a] = butter(comp.filterOrder, comp.heartLow, "low");
            else
                [b, a] = butter(comp.filterOrder, [comp.breathHigh comp.breathLow], "bandpass");
            end

            filteredSignal = filter(b, a, comp.data);
            result = filteredSignal;
        end
        
        % display filtered data
        function displayFilteredSignal(comp)
            plottedGraph = comp.plotFiltered();
            
            comp.filteredData = comp.applyFilter();
            disp(comp.filteredData);

            set(plottedGraph, 'XData', comp.time, 'YData', normalize(comp.filteredData));
            if mod(comp.count, 1) == 0
                axis(comp.UIAxes_2, [comp.time(comp.count)-20 comp.time(comp.count) comp.yMin comp.yMax]);
            end
        end
        
        function displayFFTGraph(comp)
            freqPlot = plot(comp.UIAxes_3, 0, 0, 'r');  % You can customize the plot appearance
            grid(comp.UIAxes_3, 'on');

            % Calculate and update FFT plot
            xdft = fft(comp.data);
            xdft = xdft(1:length(comp.data)/2+1);
            DF = comp.fs/length(comp.data); % frequency increment
            freqvec = 0:DF:comp.fs/2;
            set(freqPlot, 'XData', freqvec, 'YData', abs(xdft));

            freqPlot = plot(comp.UIAxes_4, 0, 0, 'b');  % You can customize the plot appearance
            grid(comp.UIAxes_4, 'on');

            % Calculate and update FFT plot
            xdft = fft(comp.filteredData);
            xdft = xdft(1:length(comp.filteredData)/2+1);
            DF = comp.fs/length(comp.filteredData); % frequency increment
            freqvec = 0:DF:comp.fs/2;
            set(freqPlot, 'XData', freqvec, 'YData', abs(xdft));

        end
        
        function displayBPM(comp)
           % Ensure 'MinPeakDistance' is a scalar with value less than 10
           % Set a fixed value for MinPeakDistance
           fixedMinPeakDistance = 5;
        
           % Adjust MinPeakDistance based on the length of the data
           minPeakDistance = min(fixedMinPeakDistance, numel(comp.data) - 1);
           [~, locs] = findpeaks(comp.data, 'MinPeakHeight', 0.5, 'MinPeakDistance', minPeakDistance);
           % Calculate heart rate (BPM)
           locs_length = length(locs);
           %disp(['Time Array: ', num2str(comp.time)]);
           disp(['Length of comp.time: ', num2str(length(comp.time))]);
           disp(['Number of Peaks: ', num2str(locs_length)]);


           if locs_length > 1
                % Calculate time in seconds between the first and last peak
                timeBetweenPeaks = comp.time(locs(end)) - comp.time(locs(1));
                            
                % Calculate heart rate
                beats = 60 * (locs_length - 1) / timeBetweenPeaks;
                            
                % Update the BPM in the UI
                comp.Label_4.Text = num2str(round(beats));
           end 
        end

        function startDisplay2Graphs(comp)

            warning('off','all');
            if ishandle(comp.UIAxes)
               cla(comp.UIAxes);
            end
            if ishandle(comp.UIAxes_2)
               cla(comp.UIAxes_2);
            end
            comp.Label_4.Text = '---';
            comp.data = [];
            comp.time = [];
            comp.count = 0;
            
            plottedGraph = comp.plotOrginalData();

            tic
            while strcmp(comp.Switch.Value, 'On')
                signal = readVoltage(comp.arduinoObj, comp.pinNumber);
                comp.count = comp.count + 1;
                comp.time(comp.count) = toc;
                comp.data(comp.count) = signal(1);
                set(plottedGraph, 'XData', comp.time, 'YData', normalize(comp.data));
                if mod(comp.count, 1) == 0
                    axis(comp.UIAxes, [comp.time(comp.count)-20 comp.time(comp.count) comp.yMin comp.yMax]);
                end

                if length(comp.time) > 10
                    comp.displayFilteredSignal();
                    comp.displayFFTGraph();
                    comp.displayBPM();
                end
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Knob2
        function Knob2ValueChanged(comp, event)
            value = comp.Knob2.Value;
            comp.filterType = value;

        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(comp, event)
            value = comp.Slider_2.Value;
            comp.heartLow = value;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(comp, event)
            value = comp.DropDown.Value;
            try
                comp.port = value;
                % Create a new Arduino object
                comp.arduinoObj = arduino(comp.port, 'Uno');
                comp.Lamp.Color = [0, 1, 1];  % Set color to cyan for successful connection
            catch
                comp.Lamp.Color = [1, 0, 0];  % Set color to red for connection error
                disp('Arduino connection failed');
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(comp, event)
            value = comp.Switch.Value;
            if strcmp(value, 'On')
                comp.Lamp.Color = [0.39,0.83,0.07];
                comp.startDisplay2Graphs();
            else 
                comp.Lamp.Color = [0.65,0.65,0.65];
            end
        end

        % Button pushed function: RECORDButton
        function RECORDButtonPushed(comp, event)
                [filename, pathname] = uiputfile({'*.mat', 'MAT Files (*.mat)'; '*.txt', 'Text Files (*.txt)'}, 'Save Signal Data');
                % Check if the user clicked Cancel
                if isequal(filename, 0) || isequal(pathname, 0)
                    disp('User canceled the operation.');
                else
                    % Construct the full file path
                    file_path = fullfile(pathname, filename);
                    
                    % Save the data (replace orginal Signal with your actual variable)
                     myData.data = comp.data;
                     myData.time = comp.time;
                     save(file_path, 'myData');
                
                    disp(['Data saved to: ' file_path]);
                end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(comp, event)
                [filename, pathname] = uigetfile({'*.mat', 'MAT Files (*.mat)'; '*.txt', 'Text Files (*.txt)'}, 'Select Data File to Export');
        
                % Check if the user clicked Cancel
                if isequal(filename, 0) || isequal(pathname, 0)
                    disp('User canceled the operation.');
                else
                    % Construct the full file path
                    file_path = fullfile(pathname, filename);
                    
                    % Load the saved data
                    loadedData = load(file_path);
                    
                    % Extract data and time from the loaded structure
                    exportedData = loadedData.myData;
                    comp.storedData = exportedData;

                    if ishandle(comp.UIAxes)
                       cla(comp.UIAxes);
                    end
                    if ishandle(comp.UIAxes_2)
                       cla(comp.UIAxes_2);
                    end
                    
                    comp.data = [];
                    comp.time = [];
                    comp.count = 0;
                   

                    comp.data= exportedData.data;
                    comp.time= exportedData.time;
                    comp.count = length(exportedData.time);

                    %plot(comp.UIAxes, comp.time, comp.data);

                    plottedGraph = comp.plotOrginalData();
                    set(plottedGraph, 'XData', comp.time, 'YData', normalize(comp.data));
                    if mod(comp.count, 1) == 0
                        axis(comp.UIAxes, [comp.time(comp.count)-20 comp.time(comp.count) comp.yMin comp.yMax]);
                    end

                    comp.displayFilteredSignal();
                    comp.displayFFTGraph();
                    %comp.displayBPM();

                    fixedMinPeakDistance = 5;
        
                    % Adjust MinPeakDistance based on the length of the data
                    minPeakDistance = min(fixedMinPeakDistance, numel(exportedData.data) - 1);
                    [~, locs] = findpeaks(exportedData.data, 'MinPeakHeight', 0.5, 'MinPeakDistance', minPeakDistance);
                    % Calculate heart rate (BPM)
                    disp('locs:');
                    disp(locs);
                    locs_length = length(locs);
                    disp('locs_lengh:');
                    disp(locs_length);
                    disp('expoerted data time:');
                    disp(length(exportedData.time));


                    Fs = 8000;
                    for k = 1 : locs_length
                      a = locs(k);
                      sound(a,Fs)
                      pause(0.7)
                      
                    end
                    
                    % Perform export operation using exportedData.data and exportedData.time
                    
                    % For example, you can display the loaded data in the MATLAB Command Window
                    %disp('Loaded Data:');
                    %disp(['Data: ' num2str(exportedData.data)]);
                    %disp(['Time: ' num2str(exportedData.time)]);
                    
                    % Add your export logic here using exportedData.data and exportedData.time
                    % For instance, you might want to update some UI components or export to another format.
                    % ...
                    % Plot the loaded data in comp.ExportedDataGraph


                    % Customize the plot if needed
                    title(comp.UIAxes, 'Loaded Data');
                    xlabel(comp.UIAxes, 'Time');
                    ylabel(comp.UIAxes, 'Amplitude');
                end
        end

        % Value changed function: Slider
        function SliderValueChanged(comp, event)
            value = comp.Slider.Value;
            comp.breathLow = value(1);
            comp.breathHigh = value(2);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 910 555];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.UIAxes = uiaxes(comp);
            title(comp.UIAxes, 'Real-time Audio Signal Plot')
            xlabel(comp.UIAxes, 'Time (s)')
            ylabel(comp.UIAxes, 'Amplitude')
            zlabel(comp.UIAxes, 'Z')
            comp.UIAxes.XLim = [-1 1];
            comp.UIAxes.CLim = [0 1];
            comp.UIAxes.ALim = [0 1];
            comp.UIAxes_2 = uiaxes(comp);
            title(comp.UIAxes_2, 'Filtered Audio Signal Plot')
            xlabel(comp.UIAxes_2, 'Time (s)')
            ylabel(comp.UIAxes_2, 'Amplitude ')
            zlabel(comp.UIAxes_2, 'Z')
            comp.UIAxes_2.XLim = [-1 1];
            comp.UIAxes_3 = uiaxes(comp);
            title(comp.UIAxes_3, 'FFT of Orginal Signal')
            xlabel(comp.UIAxes_3, 'Frequency (Hz)')
            ylabel(comp.UIAxes_3, 'Amplitude ')
            zlabel(comp.UIAxes_3, 'Z')
            comp.UIAxes_4 = uiaxes(comp);
            title(comp.UIAxes_4, 'FFT of Filtered Signal')
            xlabel(comp.UIAxes_4, 'Frequency (Hz)')
            ylabel(comp.UIAxes_4, 'Amplitude ')
            zlabel(comp.UIAxes_4, 'Z')
            comp.Label_3 = uilabel(comp);
            comp.Label_3.Text = '';
            comp.Label_4 = uilabel(comp);
            comp.Label_4.Text = '---';
            comp.BPMLabel = uilabel(comp);
            comp.BPMLabel.Text = 'BPM ';
            comp.Label_5 = uilabel(comp);
            comp.Label_5.Text = '';
            comp.Knob2 = uiknob(comp, 'discrete');
            comp.Knob2.Items = {'Heart', 'Lung'};
            comp.Knob2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Knob2ValueChanged, true);
            comp.Knob2.Value = 'Heart';
            comp.LowFcforHeartLabel = uilabel(comp);
            comp.LowFcforHeartLabel.Text = 'Low Fc for Heart';
            comp.BandpassFcsforLungLabel = uilabel(comp);
            comp.BandpassFcsforLungLabel.Text = 'Bandpass Fc''s for Lung';
            comp.Slider = uislider(comp, 'range');
            comp.Slider.Limits = [0.01 0.99];
            comp.Slider.MajorTicks = [0 0.25 0.5 0.75 1];
            comp.Slider.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1'};
            comp.Slider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SliderValueChanged, true);
            comp.Slider.Value = [0.2 0.4];
            comp.Slider_2 = uislider(comp);
            comp.Slider_2.Limits = [0.01 0.9];
            comp.Slider_2.MajorTicks = [0 0.25 0.5 0.75 1];
            comp.Slider_2.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1'};
            comp.Slider_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Slider_2ValueChanged, true);
            comp.Slider_2.Value = 0.2;
            comp.Label_6 = uilabel(comp);
            comp.Label_6.Text = '';
            comp.Lamp = uilamp(comp);
            comp.Switch = uiswitch(comp, 'toggle');
            comp.Switch.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SwitchValueChanged, true);
            comp.DropDown = uidropdown(comp);
            comp.DropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8'};
            comp.DropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @DropDownValueChanged, true);
            comp.DropDown.Value = 'COM1';
            comp.RECORDButton = uibutton(comp, 'push');
            comp.RECORDButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RECORDButtonPushed, true);
            comp.RECORDButton.Text = 'RECORD';
            comp.ExportDataButton = uibutton(comp, 'push');
            comp.ExportDataButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ExportDataButtonPushed, true);
            comp.ExportDataButton.Text = 'Export Data';
            comp.Label_7 = uilabel(comp);
            comp.Label_7.Text = '🫁';
            comp.Label_8 = uilabel(comp);
            comp.Label_8.Text = '🫀';
        end
    end
end